classdef Game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        deck      matlab.ui.control.Image
        discard   matlab.ui.control.Image
        Card1     matlab.ui.control.Image
        Card2     matlab.ui.control.Image
        Card3     matlab.ui.control.Image
        Card4     matlab.ui.control.Image
        Card5     matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function Move(app, obj, finalPosition)
            % Inputs: obj := component to be moved
            %         finalPosition := position vector for final destination
           
            % Parameterize in form xf = xi + t*v for 0<t<1
            % where xf:= finalPosition
            %       xi:= initialPosition
            %       dirVect:= direction vector from xi to xf
            
            % Get initial position of object
            initialPosition = obj.Position;
            
            % v = xf - xi
            dirVect = finalPosition - initialPosition;
            
            % 0 < t < 1
            t = linspace(0,1,20);
            
            % Animate by updating object position for t from 0 to 1
            for i = 1:20
                obj.Position = initialPosition + t(i)*dirVect;
                pause(1/160); % Seconds per frame
            end
        end
        
        function shrink(app, current)
                % Compresses the image horizontally in increments.
                %   Visually simulates 'Turning Over' a card
                % inputs: current:= image object to be shrunk
                
                % Use t to create a frame counter
                t = linspace(0,1,11);
                
                % Read current image from image filename
                %   Images read in this way are (m x n x 3) matrices
                %   m:= # of vertical pixels
                %   n:= # of horizontal pixels
                currentImage = imread(current.ImageSource);
                
                % Animation Loop, t frames
                for i = 1:length(t)-1    
                    
                    % Calculates new size vector for resized image 
                    %   Multiplies current image size by [1, 1-t]
                    %   Vertical component unchanged
                    %   Horizontal compression by factor of (1-t)
                    sizeChange = size(currentImage, [1,2]) .* [1, 1-t(i)];
                    
                    % Calls imresize(image, newSizeVector) to resize image
                    newImage = imresize(currentImage, sizeChange);
                    
                    % Sets app.Image to newImage and pauses
                    current.ImageSource = newImage;
                    pause(1/200)  % Seconds for each frame
                    
                end 
        end
        
        function grow(app, current, updated)
                % Same basic structure as shrink() but in reverse
                %       Simulates 2nd half of card turnover
                % inputs:   current := app.Image object
                %           updated := new image filename char array
                t = linspace(0,1,11);
                
                % Read new image from new image filename
                currentImage = imread(updated);
                
                % Animation loop
                for i = 2:length(t)
                    sizeChange = size(currentImage, [1,2]) .* [1, t(i)];
                    newImage = imresize(currentImage, sizeChange);
                    current.ImageSource = newImage;
                    pause(1/200)
                end
                current.ImageSource = updated;
        end
        
        function FlipCard(app, card, cardImage)
            faceImage = card.faceImage;
            backImage = 'back_image.png';
            
            % Compare current image to filename as strings to determine
            %   if card is currently showing face up or face down
            
            if  strcmpi(cardImage.ImageSource, faceImage)
                
                %If the face is showing
                shrink(app, cardImage);
                grow(app, cardImage, backImage);
            
            else
               
                %If the back is showing
                shrink(app, cardImage);
                grow(app, cardImage, faceImage);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % TEMPORARILY set number of players to 1 for development
            %   Future versions will take playerCount from user input
            playerCount = 1;
            
            deck = Shuffle(NewStandardDeck);
            
            [deal, deck] = newDeal(playerCount, 5, deck);
            pause(1)
            
            for i = 1:playerCount
                player(i) = Player();
                player.card = deal(i,:);
            end
            
            hand = [app.Card1 app.Card2 app.Card3 app.Card4 app.Card5];
            pause(2)
            % For every card (image) in the hand
            for i = 1:length(hand)
                
                % Set image for each card in the hand using GetCardImage
                hand(i).ImageSource = player(1).card(i).faceImage;
                
                % Save original card position
                card_Position = hand(i).Position;
                
                % Reset card image to deck location and make it visible
                hand(i).Position = app.deck.Position;
                hand(i).Visible = 'on';
                
                % Call Move to animate card moving from deck to original
                % position
                Move(app, hand(i), card_Position)
                
            end
            pause(1)
            discardPosition = app.discard.Position;
            app.discard.ImageSource = deck(1).backImage;
            app.discard.Position = app.deck.Position;
            app.discard.Visible = 'on';
            FlipCard(app, deck(1), app.discard)
            deck(1) = [];
            Move(app, app.discard, discardPosition)
            
        end

        % Image clicked function: Card1, Card2, Card3, Card4, Card5
        function Play(app, event)
            trueLocation = event.Source.Position;
            Move(app, event.Source, app.discard.Position);
            discardImage = event.Source.ImageSource;
            app.discard.ImageSource = discardImage;
            event.Source.Visible = 'off';
            event.Source.Position = trueLocation;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.deck = uiimage(app.UIFigure);
            app.deck.ImageSource = 'back_image.png';
            app.discard = uiimage(app.UIFigure);
            app.discard.Visible = 'off';
            app.Card1 = uiimage(app.UIFigure);
            app.Card1.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.Card1.Visible = 'off';
            app.Card2 = uiimage(app.UIFigure);
            app.Card2.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.Card2.Visible = 'off';
            app.Card3 = uiimage(app.UIFigure);
            app.Card3.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.Card3.Visible = 'off';
            app.Card4 = uiimage(app.UIFigure);
            app.Card4.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.Card4.Visible = 'off';
            app.Card5 = uiimage(app.UIFigure);
            app.Card5.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.Card5.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end