classdef Simple_Dealer_Animation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        hand1       matlab.ui.control.Image
        hand2       matlab.ui.control.Image
        hand3       matlab.ui.control.Image
        hand4       matlab.ui.control.Image
        hand5       matlab.ui.control.Image
        deck        matlab.ui.control.Image
        discard     matlab.ui.control.Image
        DealButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function Move(app, obj, finalPosition)
            % Inputs: obj := component to be moved
            %         finalPosition := position vector for final destination
           
            % Parameterize in form xf = xi + t*v for 0<t<1
            % where xf:= finalPosition
            %       xi:= initialPosition
            %       dirVect:= direction vector from xi to xf
            
            % Get initial position of object
            initialPosition = obj.Position;
            
            % v = xf - xi
            dirVect = finalPosition - initialPosition;
            
            % 0 < t < 1
            t = linspace(0,1,20);
            
            % Animate by updating object position for t from 0 to 1
            for i = 1:20
                obj.Position = initialPosition + t(i)*dirVect;
                pause(1/160); % Seconds per frame
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DealButton
        function Deal(app, event)
            % Create a new deck and shuffle
            deck = NewStandardDeck;
            deck = Shuffle(deck);
            
            % Create a new deal, cards are structure arrays 
            % w/ string fields: name, suit
            deal = newDeal(1, 5, deck);
            
            % Array hand image objects for easy indexing
            hand = [app.hand1 app.hand2 app.hand3 app.hand4 app.hand5];
            
            % For every card in the hand
            for i = 1:length(hand)
                
                % Set image for each card in the deal using GetCardImage
                hand(i).ImageSource = deal(i).faceImage;
                
                % Save original card position
                card_Position = hand(i).Position;
                
                % Reset card image to deck location and make it visible
                hand(i).Position = app.deck.Position;
                hand(i).Visible = 'on';
                
                % Call Move to animate card moving from deck to original
                % position
                Move(app, hand(i), card_Position)
            end
        end

        % Image clicked function: hand1, hand2, hand3, hand4, hand5
        function Play(app, event)
            trueLocation = event.Source.Position;
            Move(app, event.Source, app.discard.Position);
            discardImage = event.Source.ImageSource;
            app.discard.ImageSource = discardImage;
            event.Source.Visible = 'off';
            event.Source.Position = trueLocation;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.hand1 = uiimage(app.UIFigure);
            app.hand1.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.hand1.Visible = 'off';
            app.hand2 = uiimage(app.UIFigure);
            app.hand2.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.hand2.Visible = 'off';
            app.hand3 = uiimage(app.UIFigure);
            app.hand3.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.hand3.Visible = 'off';
            app.hand4 = uiimage(app.UIFigure);
            app.hand4.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.hand4.Visible = 'off';
            app.hand5 = uiimage(app.UIFigure);
            app.hand5.ImageClickedFcn = createCallbackFcn(app, @Play, true);
            app.hand5.Visible = 'off';
            app.deck = uiimage(app.UIFigure);
            app.deck.ImageSource = 'back_image.png';
            app.discard = uiimage(app.UIFigure);
            app.discard.ImageSource = 'red_joker.png';
            app.DealButton = uibutton(app.UIFigure, 'push');
            app.DealButton.ButtonPushedFcn = createCallbackFcn(app, @Deal, true);
            app.DealButton.Text = 'Deal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simple_Dealer_Animation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end